classdef app_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExperimentselectorUIFigure   matlab.ui.Figure
        HomeMenu                     matlab.ui.container.Menu
        ChangedirectoryMenu          matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        FiltersMenu                  matlab.ui.container.Menu
        ProcessMenu                  matlab.ui.container.Menu
        SelectscriptMenu             matlab.ui.container.Menu
        SaveselectionMenu            matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        ExptSlider                   matlab.ui.control.Slider
        ExperimentsliderSliderLabel  matlab.ui.control.Label
        GridLayout5_2                matlab.ui.container.GridLayout
        ImageSlider                  matlab.ui.control.Slider
        ImageindexsliderSliderLabel  matlab.ui.control.Label
        hiddenGL                     matlab.ui.container.GridLayout
        FolderTreeHidden             matlab.ui.container.CheckBoxTree
        GridLayout4                  matlab.ui.container.GridLayout
        OpticalFlowButton            matlab.ui.control.StateButton
        statuslabel                  matlab.ui.control.Label
        ExpandButton                 matlab.ui.control.Button
        CollapseButton               matlab.ui.control.Button
        SelectallButton              matlab.ui.control.Button
        FolderTree                   matlab.ui.container.CheckBoxTree
        FilteringPanel               matlab.ui.container.Panel
        popoutGL                     matlab.ui.container.GridLayout
        LabelEditField               matlab.ui.control.EditField
        LabelEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)
        root
        f,f0
        parentnodes
        expts
        fig
        imIndex = 1
        openExpt = {}
        openExptData
    end
    properties (Access = private)
        scrolltype
        scrollmult          % Scroll wheel multiplier
        scrolltags
    end
    properties (Access = private, Hidden)
        checkedNodes
        hiddenTree
        hiddenNodes
        lastfilter
        vfapp
        uitimer
    end
    
    methods (Access = private)
        
        function setSliderLimits(app)
            count                  = numel(app.expts);
            if count==1
                app.ExptSlider.Enable = 'off';
                return
            end
            app.ExptSlider.Enable = 'on';
            app.ExptSlider.Value   = 1;
            app.ExptSlider.Limits  = [1 count];
            app.ExptSlider.MajorTicks = 1:count;

        end
        function setImageSliderLimits(app)
            time                    = set_sliderlimits(app);
            app.ImageSlider.Value   = 1;
            app.ImageSlider.Limits  = [1 time];
            app.ImageSlider.MajorTicks = [1 10^floor(log10(time)):10^floor(log10(time)):time];
        end
    end
    
    methods (Access = public) % Image methods    
        function previewImage(app)
            if isempty(app.openExpt)
                return
            end
            assignin('base','exptfile',app.openExpt)

            % resolution
            if isempty(app.uitimer)
                app.uitimer = timer('Name','uitimer',...
                    "BusyMode","drop",'StartDelay',1, ...
                    'TimerFcn',@improveResolution, ...
                    'ErrorFcn',@timerError);
            end
            try
                start(app.uitimer)
            catch
                stop(app.uitimer)
                previewExpt(app,100);
            end
            %             end
            function improveResolution(~,~)
                previewExpt(app,500)
                stop(app.uitimer)
            end
        end
    end
    methods (Access = public) % Folder methods
        function assignment(app)

            checked = app.FolderTree.CheckedNodes;
            if isempty(checked)
                return
            end
            omit        = app.parentnodes;
            selection   = setdiff(checked,omit);
            app.checkedNodes = selection;

            path        = @(x) fullfile(x.folder,x.name);
            try
                arefiles    = cell2mat(arrayfun(@(x) isfile(path(x.UserData)),selection,'UniformOutput',false));
            catch
                arefiles    = false(size(selection));
            end

            selection_root_files = {};
            if any(arefiles)
                selection_root_files = arrayfun(@(x) path(x.UserData),selection(arefiles),'UniformOutput',false);
            end
            selection_root = {selection(~arefiles).UserData}';%cellfun(@(x) x.folder,{selection(~arefiles).UserData},'UniformOutput',false)';
            selection_root = strcat(selection_root,set_foldering);
            selection_full = cat(1,selection_root,selection_root_files);
            selection_full = strrep(selection_full,'images','results');

            assignin('base','expts',sort(selection_full))
            app.expts = selection_full;

        end
        function parseOpticalFlow(app)
            if any(isequal(app.FolderTree.SelectedNodes,app.parentnodes))
                return
            end

            if isempty(app.vfapp)||isempty(app.FolderTree.CheckedNodes)
                return
            end
            app.openExptData = exptdata(app.FolderTree.SelectedNodes.UserData);
            app.vfapp.updateExpt(app.openExptData)
        end
        function locateNode(app,islocated)
            if nargin<2
                islocated = false;
            end
            path        = @(x) fullfile(x.folder,x.name);
            list = arrayfun(@(x) path(x.UserData),app.checkedNodes,'UniformOutput',false);
            if ~islocated
                app.FolderTree.SelectedNodes = app.checkedNodes(contains(list,app.openExpt));
                return
            end
            if any(app.FolderTree.SelectedNodes==app.parentnodes)
                return
            end
            app.openExpt            = path(app.FolderTree.SelectedNodes.UserData);
            val                     = find(contains(app.expts,app.openExpt));
            if isempty(val)
                return
            end
            app.ExptSlider.Value    = val;
            setSliderLimits(app)
            setImageSliderLimits(app)
            previewImage(app)
        end
        function folderCallback(app,root)
            app.f = Folder(root);
            app.f.maketree('handle',app.FolderTree,'files','filter',set_foldering)
        end
        function changeDir(app)
            app.root = uigetdir(cd,'Select new root directory');
        end
        function labelFilter(app,filter)
            nodes       = findobj(app.FolderTree,'Type','uitreenode');
            hnodes      = findobj(app.hiddenTree,'Type','uitreenode');
            nodellabels = arrayfun(@(x) x.Text,nodes,'UniformOutput',false);
            select      = contains(nodellabels,filter);
            %                 select0     = select;
            parentnode  = [];
            arrayfun(@getParentNodes,nodes(select))
            app.hiddenNodes = hnodes(~select);
            delete(nodes(~select));
            app.parentnodes = unique(parentnode);%nodes(logical(select-select0));

            function getParentNodes(obj)
                parent      = obj.Parent;
                istoplevel  = contains(class(parent),'CheckBoxTree');
                isafile     = contains(class(parent.UserData),'char');
                if istoplevel
                    parentnode = cat(1,parentnode,obj);
                    if isafile
                        parentnode = [];
                    end
                    return
                end
                select = logical(select+contains(arrayfun(@(x) x.Text,nodes,'UniformOutput',false),parent.Text));
                getParentNodes(parent)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'))
            app.root = set_startup;
            app.GridLayout.ColumnWidth = {'4x',0};
            app.hiddenGL.RowHeight = {30,'1x',0};
            app.statuslabel.Text = 'Building directory tree';
            pause(1)
            try
                folderCallback(app,app.root)
            catch 
                changeDir(app)
                folderCallback(app,app.root)
            end
            app.f0 = app.f;
            app.hiddenTree = app.FolderTree;
            app.statuslabel.Text = '';
        end

        % Menu selected function: FiltersMenu
        function filterToolbarCallback(app, event)
            if ~event.Source.Checked
                app.GridLayout.ColumnWidth = {'4x',200};
                event.Source.Checked = true;
                return
            end
            app.GridLayout.ColumnWidth = {'4x',0};
            event.Source.Checked = false;
        end

        % Value changed function: LabelEditField
        function filteringCallback(app, event)
            command = split(event.Source.Tag,'-');
            command = command{end};
            isfiltering = false;
            switch command
                case 'label'
                    isfiltering  = true;
                    label2filter = event.Source.Value;
                    if isempty(label2filter)
                        app.f0.maketree('handle',app.FolderTree,'files','filter',set_foldering)
                    end
                    if isempty(app.lastfilter)
                        app.lastfilter = label2filter;
                    end
            end

            if isfiltering
                % check for changed filter
                if ~isequal(app.lastfilter,label2filter)
                    [];
                end
                % check for empty filter
                labelFilter(app,label2filter)
            end

        end

        % Button pushed function: CollapseButton, ExpandButton, 
        % SelectallButton
        function nodeCallbacks(app, event)
            persistent chk
            opennodes       = app.FolderTree.SelectedNodes;
            switch event.Source.Tag
                case 'all'
                    if isempty(chk)
                        chk = 1;
                        pause(0.5); %Add a delay to distinguish single click from a double click
                        if chk == 1
                            app.FolderTree.CheckedNodes = findobj(app.FolderTree,'Type','uitreenode');
                            chk = [];
                        end
                    else
                        chk = [];
                        app.FolderTree.CheckedNodes = [];
                        locateNode(app)
                    end
                    assignment(app)
                case 'expand'
                    if isempty(chk)
                        chk = 1;
                        pause(0.5); %Add a delay to distinguish single click from a double click
                        if chk == 1
                            if isempty(opennodes)
                                expand(app.FolderTree);
                            else
                                expand(opennodes)
                            end
                            chk = [];
                        end
                    else
                        chk = [];
                        expand(app.FolderTree)
                    end
                case 'collapse'
                    if isempty(chk)
                        chk = 1;
                        pause(0.5); %Add a delay to distinguish single click from a double click
                        if chk == 1
                            if isempty(opennodes)
                                collapse(app.FolderTree);
                            else
                                collapse(opennodes)
                            end
                            chk = [];
                        end
                    else
                        chk = [];
                        collapse(app.FolderTree)
                    end
            end
        end

        % Callback function
        function pushAssignment(app, event)
            labelFilter(app,'')
            assignment(app)
        end

        % Menu selected function: ChangedirectoryMenu
        function newDirCallback(app, event)
            newroot = uigetdir(app.root,'Select new directory');

            if isempty(newroot)
                return
            end
            app.root = newroot;
            startupFcn(app)
        end

        % Window scroll wheel function: ExperimentselectorUIFigure
        function ExperimentselectorUIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            hFound              = pointer2object(app.ExperimentselectorUIFigure,{'image','slidergl'});
            if isempty(hFound)
                return
            end
            switch hFound.Type
                case {'uislider','uigridlayout'}
                    isslider = contains(hFound.Type,'slider');
                    hasslider = 0;
                    if ~isslider
                        hasslider = contains({hFound.Children.Type},'slider');
                    end
                    if ~any([isslider hasslider])
                        return
                    end
                    if ~isslider
                        hFound = hFound.Children(hasslider);
                    end
                    step    = 10;%(hFound.Limits(2)-hFound.Limits(1))/100;
                    if app.scrollmult
                        switch app.scrolltype
                            case 'shift'
                                step = step*5;
                            case 'control'
                                step = 1;
                        end
                    end

                    val     = hFound.Value - verticalScrollCount*step;

                    if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = hFound.Value - verticalScrollCount*step;
                    end
                    app.imIndex = round(hFound.Value);
                    previewImage(app)
                otherwise
            end
        end

        % Window key press function: ExperimentselectorUIFigure
        function ExperimentselectorUIFigureWindowKeyPress(app, event)
            if contains(event.Key,{'shift','control'})
                app.scrollmult = true;
                app.scrolltype = event.Key;
            end
        end

        % Window key release function: ExperimentselectorUIFigure
        function ExperimentselectorUIFigureWindowKeyRelease(app, event)
            app.scrollmult = false;
        end

        % Callback function: ExptSlider, ExptSlider, FolderTree, 
        % ImageSlider, ImageSlider
        function sliderCallback(app, event)

            switch event.Source.Tag
                case 'expt'
                    event.Source.Value = round(event.Source.Value);
                    app.openExpt = app.expts(app.ExptSlider.Value);
                    % highlight folder/file in tree
                    locateNode(app)
%                     app.FolderTree.SelectedNodes
                case 'image'
                    event.Source.Value = round(event.Source.Value);
                    app.imIndex = app.ImageSlider.Value;
                case 'tree'
                    if isempty(app.FolderTree.CheckedNodes)
                        return
                    end
                    locateNode(app,true)
                    parseOpticalFlow(app)
            end

            previewImage(app)
        end

        % Callback function: FolderTree
        function assignExpts(app, event)
            labelFilter(app,'')
            assignment(app)
            app.openExpt        = app.expts{1};
            if isempty(app.expts)
                return
            end
            locateNode(app)
            setSliderLimits(app)
            setImageSliderLimits(app)
            previewImage(app)
        end

        % Value changed function: OpticalFlowButton
        function opticalFlowCallback(app, event)
            if numel(app.FolderTree.CheckedNodes)==0
                warning('A node must be checked to open OpticalFlow')
                return
            end
            
            locateNode(app)
            app.openExptData = exptdata(app.FolderTree.SelectedNodes.UserData);
            if ~event.PreviousValue
                app.vfapp = app_velocityfield(app.openExptData,app);
            else
                delete(app.vfapp)
                app.vfapp = [];
            end
        end

        % Menu selected function: SelectscriptMenu
        function processMenu(app, event)
           % Unscripted
            switch event.Source.Tag
                case 'script'
                    set_script(app.expts)
                case 'save'
                    set_save(app.expts)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExperimentselectorUIFigure = uifigure('Visible', 'off');
            app.ExperimentselectorUIFigure.Name = 'Experiment selector';
            app.ExperimentselectorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ExperimentselectorUIFigureWindowScrollWheel, true);
            app.ExperimentselectorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ExperimentselectorUIFigureWindowKeyPress, true);
            app.ExperimentselectorUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @ExperimentselectorUIFigureWindowKeyRelease, true);
            app.HomeMenu = uimenu(app.ExperimentselectorUIFigure);
            app.HomeMenu.Text = 'Home';
            app.ChangedirectoryMenu = uimenu(app.HomeMenu);
            app.ChangedirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @newDirCallback, true);
            app.ChangedirectoryMenu.Text = 'Change directory';
            app.ViewMenu = uimenu(app.ExperimentselectorUIFigure);
            app.ViewMenu.Text = 'View';
            app.FiltersMenu = uimenu(app.ViewMenu);
            app.FiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @filterToolbarCallback, true);
            app.FiltersMenu.Text = 'Filters';
            app.ProcessMenu = uimenu(app.ExperimentselectorUIFigure);
            app.ProcessMenu.Visible = 'off';
            app.ProcessMenu.Text = 'Process';
            app.SelectscriptMenu = uimenu(app.ProcessMenu);
            app.SelectscriptMenu.MenuSelectedFcn = createCallbackFcn(app, @processMenu, true);
            app.SelectscriptMenu.Text = 'Select script';
            app.SelectscriptMenu.Tag = 'script';
            app.SaveselectionMenu = uimenu(app.ProcessMenu);
            app.SaveselectionMenu.Text = 'Save selection';
            app.SaveselectionMenu.Tag = 'save';
            app.GridLayout = uigridlayout(app.ExperimentselectorUIFigure);
            app.FilteringPanel = uipanel(app.GridLayout);
            app.FilteringPanel.Title = 'Filtering';
            app.popoutGL = uigridlayout(app.FilteringPanel);
            app.LabelEditFieldLabel = uilabel(app.popoutGL);
            app.LabelEditField = uieditfield(app.popoutGL, 'text');
            app.LabelEditField.ValueChangedFcn = createCallbackFcn(app, @filteringCallback, true);
            app.LabelEditField.Tag = 'filter-label';
            app.hiddenGL = uigridlayout(app.GridLayout);
            app.FolderTree = uitree(app.hiddenGL, 'checkbox');
            app.FolderTree.SelectionChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.FolderTree.Tag = 'tree';
            % Assign Checked Nodes
            app.FolderTree.CheckedNodesChangedFcn = createCallbackFcn(app, @assignExpts, true);
            app.GridLayout4 = uigridlayout(app.hiddenGL);
            app.SelectallButton = uibutton(app.GridLayout4, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @nodeCallbacks, true);
            app.SelectallButton.Tag = 'all';
            app.SelectallButton.Text = 'Select all';
            app.CollapseButton = uibutton(app.GridLayout4, 'push');
            app.CollapseButton.ButtonPushedFcn = createCallbackFcn(app, @nodeCallbacks, true);
            app.CollapseButton.Tag = 'collapse';
            app.CollapseButton.Text = 'Collapse';
            app.ExpandButton = uibutton(app.GridLayout4, 'push');
            app.ExpandButton.ButtonPushedFcn = createCallbackFcn(app, @nodeCallbacks, true);
            app.ExpandButton.Tag = 'expand';
            app.ExpandButton.Text = 'Expand';
            app.statuslabel = uilabel(app.GridLayout4);
            app.statuslabel.Text = '';
            app.OpticalFlowButton = uibutton(app.GridLayout4, 'state');
            app.OpticalFlowButton.ValueChangedFcn = createCallbackFcn(app, @opticalFlowCallback, true);
            app.OpticalFlowButton.Text = 'Optical Flow';
            app.FolderTreeHidden = uitree(app.hiddenGL, 'checkbox');
            app.FolderTreeHidden.Tag = 'tree';
            app.GridLayout5_2 = uigridlayout(app.GridLayout);
            app.GridLayout5_2.Tag = 'slidergl';
            app.ImageindexsliderSliderLabel = uilabel(app.GridLayout5_2);
            app.ImageindexsliderSliderLabel.Text = 'Image index slider';
            app.ImageSlider = uislider(app.GridLayout5_2);
            app.ImageSlider.Limits = [0 1];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @sliderCallback, true);
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Tag = 'image';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.GridLayout5.Tag = 'slidergl';
            app.ExperimentsliderSliderLabel = uilabel(app.GridLayout5);
            app.ExperimentsliderSliderLabel.Text = 'Experiment slider';
            app.ExptSlider = uislider(app.GridLayout5);
            app.ExptSlider.Limits = [0 1];
            app.ExptSlider.MajorTicks = [];
            app.ExptSlider.ValueChangedFcn = createCallbackFcn(app, @sliderCallback, true);
            app.ExptSlider.ValueChangingFcn = createCallbackFcn(app, @sliderCallback, true);
            app.ExptSlider.MinorTicks = [];
            app.ExptSlider.Tag = 'expt';
            % Show the figure after all components are created
            app.ExperimentselectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_selector

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExperimentselectorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ExperimentselectorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExperimentselectorUIFigure)
        end
    end
end