classdef app_velocityfield < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpticalFlowVelocityFieldUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        AlphaEditFieldLabel         matlab.ui.control.Label
        AlphaEditField              matlab.ui.control.NumericEditField
        methodtab                   matlab.ui.container.TabGroup
        HornSchunckTab              matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        IterationsEditFieldLabel    matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        LieShenTab                  matlab.ui.container.Tab
        GridLayout6_2               matlab.ui.container.GridLayout
        FiltersizeEditField         matlab.ui.control.NumericEditField
        FiltersizeEditFieldLabel    matlab.ui.control.Label
        IlluminationEditField       matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel  matlab.ui.control.Label
        LambdaEditFieldLabel        matlab.ui.control.Label
        LambdaEditField             matlab.ui.control.NumericEditField
        OverlayDropDown             matlab.ui.control.DropDown
        OverlayDropDownLabel        matlab.ui.control.Label
        ViewDropDown                matlab.ui.control.DropDown
        ViewDropDownLabel           matlab.ui.control.Label
        ResizeEditField             matlab.ui.control.NumericEditField
        ResizeEditFieldLabel        matlab.ui.control.Label
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        IntervalSpinner             matlab.ui.control.Spinner
        IntervalSpinnerLabel        matlab.ui.control.Label
        TimeseriesPanel             matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        Label                       matlab.ui.control.Label
        ts_activate                 matlab.ui.control.StateButton
        step_end                    matlab.ui.control.NumericEditField
        EndEditFieldLabel           matlab.ui.control.Label
        step_step                   matlab.ui.control.NumericEditField
        StepEditFieldLabel          matlab.ui.control.Label
        step_start                  matlab.ui.control.NumericEditField
        StartEditFieldLabel         matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        expt_label                  matlab.ui.control.Label
        GridLayout5                 matlab.ui.container.GridLayout
        ImageIndex                  matlab.ui.control.Slider
        ImageSliderLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        operation
        index
        output
        view = 'Image'
        overlay = 'None'
    end

    properties (Access = public)
        data
        callingApp
    end

    properties (Access = private, Hidden)
        scrolltype
        scrollmult          % Scroll wheel multiplier
        imslidertt
    end

    methods (Access = public)
        function updateExpt(app,newdata)
            app.data = newdata;
            showlabel(app)
            app.output = [];
            setImageSlider(app);
            updateAxes(app)
        end

        function showlabel(app)
            app.expt_label.Text = app.data.ncinfo.Filename;
        end
        function calculateVelocity(app)
            ops = app.operation;

            if ~ops.ts
                app.output = app.data.getVelocity(app.index,...
                    'dt',ops.dt,...
                    'method',ops.method,...
                    'alpha',ops.alpha,...
                    'iterations',ops.iterations,...
                    'resize',ops.resize, ...
                    'lambda',ops.lambda,...
                    'illumination',ops.illumination,...
                    'filter',ops.filter);
                return
            end
            
%             app.output = app.data.VelocityTimeSeries(...
%                 'parallel',...
%                 'step',ops.tsStep,...
%                 'dt',ops.dt,...
%                 'method',ops.method,...
%                 'alpha',ops.alpha,...
%                 'iterations',ops.iterations,...
%                 'resize',ops.resize);
        end

        function setImageSlider(app)
            files = 1:app.data.ncinfo.Dimensions(1).Length;
            lims = [1 numel(files)];
            app.step_start.Limits   = lims;
            app.step_step.Limits    = lims;
            app.step_end.Limits     = lims;
            app.step_end.Value      = lims(2);

            %                 steps = round(length(files),-1);
            ticks = 0:round(length(files)/5):length(files);
            ticks(1)        = 1;
            ticks(end+1)    = length(files);

            % Adjust image index slider
            app.ImageIndex.Enable       = 'on';
            app.ImageIndex.Visible      = 'on';
            app.ImageIndex.Limits       = [1 length(files)];
            app.ImageIndex.MajorTicks   = ticks;
            app.ImageIndex.MinorTicksMode  = 'auto';
            app.ImageIndex.MajorTickLabels = cellstr(num2str(ticks'))';
            app.index = app.ImageIndex.Value;

            % Update slider tooltip
            app.ImageIndex.Tooltip = sprintf('%s\nUse the scroll wheel to move %i images\nHold Ctrl to move 1 image index\nHold Shift to move %i images',...
                app.imslidertt{1},10,50);
        end

        function updateAxes(app)
            if isempty(app.output)
                calculateVelocity(app)
            end
            if ~app.operation.ts
                app.data.plotVelocity(app.index,...
                    'ax',app.UIAxes,...
                    'input',app.output,...
                    'view',app.view,...
                    'overlay',app.overlay);
            end
            assignin("base",'output',app.data)
            
            
        end

        function folderCallback(app,newdata)
            app.data = newdata;
            setImageSlider(app);
            calculateVelocity(app)
            updateAxes(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DATA, callingApp)
            app.data = DATA;
            app.imslidertt = app.ImageIndex.Tooltip;
            if nargin==3
                app.callingApp = callingApp;
            end
            app.operation = struct( ...
                'dt',1, ...
                'method','fast', ...
                'alpha',20, ...
                'iterations',2e2, ...
                'resize',1,...
                'lambda',200, ...
                'illumination',0, ...
                'filter',6,...
                'ts',false, ...
                'tsStep',[]);
            setImageSlider(app);
            pause(1e-3)
            calculateVelocity(app)
            updateAxes(app)
            showlabel(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OpticalFlowVelocityFieldUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {607, 607};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: AlphaEditField, FiltersizeEditField, 
        % IlluminationEditField, IntervalSpinner, IterationsEditField, 
        % LambdaEditField, MethodDropDown, OverlayDropDown, 
        % ResizeEditField, ViewDropDown, ts_activate
        function genericCallback(app, event)
            tag = event.Source.Tag;
            command = split(tag,'-');
            command = command{end};
            iscalc  = false;
            switch command
                case {'dt','alpha','iterations','lambda','illumination','filter','resize'}
                    task = sprintf('app.operation.%s=event.Source.Value;',command);
                    eval(task)
                    iscalc = true;
                case 'method'
                    switch event.Source.Value
                        case 'Horn-Schunck (fast)'
                            app.methodtab.SelectedTab = app.HornSchunckTab;
                            app.operation.method = 'fast';
                        case 'Liu-Shen (slow)'
                            app.methodtab.SelectedTab = app.LieShenTab;
                            app.operation.method = 'slow';
                    end
                    iscalc = true;
                case {'view','overlay'}
                    task = sprintf('app.%s=event.Source.Value;',command);
                    eval(task)
                case 'process'
                    app.operation.ts = true;
                    app.operation.tsStep = app.step_start.Value:app.step_step.Value:app.step_end.Value;
                    iscalc = true;
            end

            if iscalc
                app.Label.Text = 'Processing';
                pause(1e-3)
                tic
                calculateVelocity(app)
                app.Label.Text          = sprintf('%.2fs',toc);
            end
            updateAxes(app)
            app.operation.ts        = false;
            app.operation.tsStep    = [];
            app.ts_activate.Value   = false;
        end

        % Value changed function: ImageIndex
        function imageSliderCallback(app, event)
            app.ImageIndex.UserData = round(app.ImageIndex.Value,0);
            app.ImageIndex.Value    = app.ImageIndex.UserData;
            app.index               = app.ImageIndex.UserData;
            app.Label.Text = 'Processing';
            pause(1e-3)
            tic
            calculateVelocity(app)
            app.Label.Text = sprintf('%.2fs',toc);
            updateAxes(app)
        end

        % Window key press function: OpticalFlowVelocityFieldUIFigure
        function OpticalFlowVelocityFieldUIFigureWindowKeyPress(app, event)
            if contains(event.Key,{'shift','control'})
                app.scrollmult = true;
                app.scrolltype = event.Key;
            end
        end

        % Window key release function: OpticalFlowVelocityFieldUIFigure
        function OpticalFlowVelocityFieldUIFigureWindowKeyRelease(app, event)
            app.scrollmult = false;
        end

        % Window scroll wheel function: OpticalFlowVelocityFieldUIFigure
        function OpticalFlowVelocityFieldUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            hFound = pointer2object(app.OpticalFlowVelocityFieldUIFigure,{'index'});
            if isempty(hFound)
                return
            end
            switch hFound.Type
                case {'uislider','uigridlayout'}
                    isslider = contains(hFound.Type,'slider');
                    hasslider = contains({hFound.Children.Type},'slider');
                    if ~any([isslider hasslider])
                        return
                    end
                    if ~isslider
                        hFound = hFound.Children(hasslider);
                    end
                    if contains(hFound.Type,'slider')
                        step = 10;
                        if app.scrollmult
                            switch app.scrolltype
                                case 'shift'
                                    step = step*5;
                                case 'control'
                                    step = 1;
                            end
                        end

                        val     = hFound.Value - verticalScrollCount*step;

                        if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                            hFound.Value = hFound.Value - verticalScrollCount*step;
                        end
                        imageSliderCallback(app,event)
                    else
                        return
                    end
                    %                     imageSliderCallback(app,event)
            end
        end

        % Close request function: OpticalFlowVelocityFieldUIFigure
        function OpticalFlowVelocityFieldUIFigureCloseRequest(app, event)
            try %#ok<TRYNC> 
                app.callingApp.OpticalFlowButton.Value = 0;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpticalFlowVelocityFieldUIFigure = uifigure('Visible', 'off');
            app.OpticalFlowVelocityFieldUIFigure.Name = 'Optical Flow: Velocity Field';
            app.OpticalFlowVelocityFieldUIFigure.CloseRequestFcn = createCallbackFcn(app, @OpticalFlowVelocityFieldUIFigureCloseRequest, true);
            app.OpticalFlowVelocityFieldUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.OpticalFlowVelocityFieldUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @OpticalFlowVelocityFieldUIFigureWindowScrollWheel, true);
            app.OpticalFlowVelocityFieldUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OpticalFlowVelocityFieldUIFigureWindowKeyPress, true);
            app.OpticalFlowVelocityFieldUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @OpticalFlowVelocityFieldUIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.OpticalFlowVelocityFieldUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TimeseriesPanel = uipanel(app.GridLayout2);
            app.TimeseriesPanel.Title = 'Time series';
            app.GridLayout4 = uigridlayout(app.TimeseriesPanel);
            app.StartEditFieldLabel = uilabel(app.GridLayout4);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.step_start = uieditfield(app.GridLayout4, 'numeric');
            app.step_start.Limits = [1 Inf];
            app.step_start.Tag = 'set-t0';
            app.step_start.Enable = 'off';
            app.step_start.Value = 1;
            app.StepEditFieldLabel = uilabel(app.GridLayout4);
            app.StepEditFieldLabel.Enable = 'off';
            app.StepEditFieldLabel.Text = 'Step';
            app.step_step = uieditfield(app.GridLayout4, 'numeric');
            app.step_step.Limits = [1 Inf];
            app.step_step.Tag = 'set-ti';
            app.step_step.Enable = 'off';
            app.step_step.Value = 1;
            app.EndEditFieldLabel = uilabel(app.GridLayout4);
            app.EndEditFieldLabel.Enable = 'off';
            app.EndEditFieldLabel.Text = 'End';
            app.step_end = uieditfield(app.GridLayout4, 'numeric');
            app.step_end.Limits = [2 Inf];
            app.step_end.Tag = 'set-tf';
            app.step_end.Enable = 'off';
            app.step_end.Value = Inf;
            app.ts_activate = uibutton(app.GridLayout4, 'state');
            app.ts_activate.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.ts_activate.Tag = 'set-process';
            app.ts_activate.Enable = 'off';
            app.ts_activate.Text = 'Process time series';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            app.IntervalSpinnerLabel = uilabel(app.GridLayout2);
            app.IntervalSpinnerLabel.Text = 'Interval';
            app.IntervalSpinner = uispinner(app.GridLayout2);
            app.IntervalSpinner.Limits = [1 Inf];
            app.IntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.IntervalSpinner.Tag = 'set-dt';
            app.IntervalSpinner.Tooltip = {'Interval between images for velocity field calculation'};
            app.IntervalSpinner.Value = 1;
            app.MethodDropDownLabel = uilabel(app.GridLayout2);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout2);
            app.MethodDropDown.Items = {'Horn-Schunck (fast)', 'Liu-Shen (slow)'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.MethodDropDown.Tag = 'set-method';
            app.MethodDropDown.Tooltip = {'Optical flow velocity field calculation method'};
            app.MethodDropDown.Value = 'Horn-Schunck (fast)';
            app.ResizeEditFieldLabel = uilabel(app.GridLayout2);
            app.ResizeEditFieldLabel.Text = 'Resize';
            app.ResizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ResizeEditField.Limits = [0 2];
            app.ResizeEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.ResizeEditField.Tag = 'set-resize';
            app.ResizeEditField.Tooltip = {'Image scale resizing (x100%)'};
            app.ResizeEditField.Value = 1;
            app.ViewDropDownLabel = uilabel(app.GridLayout2);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout2);
            app.ViewDropDown.Items = {'Image', 'u', 'v', 'U', 'Vorticity'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.ViewDropDown.Tag = 'set-view';
            app.ViewDropDown.Value = 'Image';
            app.OverlayDropDownLabel = uilabel(app.GridLayout2);
            app.OverlayDropDownLabel.Text = 'Overlay';
            app.OverlayDropDown = uidropdown(app.GridLayout2);
            app.OverlayDropDown.Items = {'None', 'Vectors', 'Streamlines'};
            app.OverlayDropDown.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.OverlayDropDown.Tag = 'set-overlay';
            app.OverlayDropDown.Value = 'None';
            app.methodtab = uitabgroup(app.GridLayout2);
            app.HornSchunckTab = uitab(app.methodtab);
            app.HornSchunckTab.Title = 'Horn-Schunck';
            app.GridLayout6 = uigridlayout(app.HornSchunckTab);
            app.IterationsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.IterationsEditField.Tag = 'set-iterations';
            app.IterationsEditField.Tooltip = {'Number of iterations for velocity field convergence'};
            app.IterationsEditField.Value = 200;
            app.IterationsEditFieldLabel = uilabel(app.GridLayout6);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.LieShenTab = uitab(app.methodtab);
            app.LieShenTab.Title = 'Lie-Shen';
            app.GridLayout6_2 = uigridlayout(app.LieShenTab);
            app.LambdaEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.LambdaEditField.Limits = [1 Inf];
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.LambdaEditField.Tag = 'set-lambda';
            app.LambdaEditField.Tooltip = {'Liu-Shen estimator for refined estimation'};
            app.LambdaEditField.Value = 200;
            app.LambdaEditFieldLabel = uilabel(app.GridLayout6_2);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.IlluminationEditFieldLabel = uilabel(app.GridLayout6_2);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.IlluminationEditField.Limits = [0 Inf];
            app.IlluminationEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.IlluminationEditField.Tag = 'set-illumination';
            app.IlluminationEditField.Tooltip = {'Local illumination intensity adjustment, To bypass it, set to 0'};
            app.FiltersizeEditFieldLabel = uilabel(app.GridLayout6_2);
            app.FiltersizeEditFieldLabel.Text = 'Filter size';
            app.FiltersizeEditField = uieditfield(app.GridLayout6_2, 'numeric');
            app.FiltersizeEditField.Limits = [1 Inf];
            app.FiltersizeEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.FiltersizeEditField.Tag = 'set-filter';
            app.FiltersizeEditField.Tooltip = {'Gausian filter size for removing random noise in images'};
            app.FiltersizeEditField.Value = 6;
            app.AlphaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AlphaEditField.Limits = [1 Inf];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @genericCallback, true);
            app.AlphaEditField.Tag = 'set-alpha';
            app.AlphaEditField.Tooltip = {'Neigbourhood search area for velocity field calculation'};
            app.AlphaEditField.Value = 20;
            app.AlphaEditFieldLabel = uilabel(app.GridLayout2);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout5.Tag = 'index';
            app.ImageSliderLabel = uilabel(app.GridLayout5);
            app.ImageSliderLabel.Text = 'Image';
            app.ImageIndex = uislider(app.GridLayout5);
            app.ImageIndex.Limits = [1 2];
            app.ImageIndex.MajorTicks = [1 2];
            app.ImageIndex.ValueChangedFcn = createCallbackFcn(app, @imageSliderCallback, true);
            app.ImageIndex.Tag = 'index';
            app.ImageIndex.Tooltip = {'Time series image index'};
            app.ImageIndex.Value = 1;
            app.expt_label = uilabel(app.GridLayout3);
            app.expt_label.Text = '';
            % Show the figure after all components are created
            app.OpticalFlowVelocityFieldUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_velocityfield(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OpticalFlowVelocityFieldUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OpticalFlowVelocityFieldUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpticalFlowVelocityFieldUIFigure)
        end
    end
end